classdef AppMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ConnectArduinoButton         matlab.ui.control.Button
        BaudRateEditField            matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel       matlab.ui.control.Label
        SerialPortEditField          matlab.ui.control.EditField
        SerialPortEditFieldLabel     matlab.ui.control.Label
        ArduinoConnectionStatusLamp  matlab.ui.control.Lamp
        ArduinoConnectionStatusLampLabel  matlab.ui.control.Label
        UseTrainedModelButton        matlab.ui.control.Button
        LocateSensorsButton          matlab.ui.control.Button
        TrainModelButton             matlab.ui.control.Button
        ContactLocalizationAppLabel  matlab.ui.control.Label
        CollectDataButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        skinObj % Arduino Connection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            connectionsList = serialportlist("available");
            if ~isempty(connectionsList)
                % Check to see what the active serial connection is
                app.SerialPortEditField.Value = connectionsList(1);
            end

            %Immediately try to connect to arduino
            ConnectArduinoButtonPushed(app);
            
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            Data_Collector_Window(app.skinObj);
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            Train_Model_Window(app.skinObj);
        end

        % Button pushed function: LocateSensorsButton
        function LocateSensorsButtonPushed(app, event)
            Locate_Sensors_Window();
        end

        % Button pushed function: UseTrainedModelButton
        function UseTrainedModelButtonPushed(app, event)
            Test_Model_Window(app.skinObj);
        end

        % Button pushed function: ConnectArduinoButton
        function ConnectArduinoButtonPushed(app, event)
            %Attempt to connect to arduino 

            app.ArduinoConnectionStatusLampLabel.Text = "Connecting to Aruduino...";
            try
                %Make Connection
                app.skinObj = serialport(app.SerialPortEditField.Value,app.BaudRateEditField.Value);

                %Debugging:
                %app.skinObj = -1;
            catch
                %Connection Failed!
                app.ArduinoConnectionStatusLampLabel.Text = "Connection Failed!";
                app.ArduinoConnectionStatusLamp.Color = [1,0,0]; %Set color to red
                app.ConnectArduinoButton.Enable = "on";
                return
            end

            % Connection Succesful!
            app.ArduinoConnectionStatusLampLabel.Text = "Connection Successful!";
            app.ArduinoConnectionStatusLamp.Color = [0,1,0]; %Set color to green
            app.CollectDataButton.Enable = "on";
            app.TrainModelButton.Enable = "on";
            app.UseTrainedModelButton.Enable = "on";
            app.ConnectArduinoButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CollectDataButton = uibutton(app.UIFigure, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Enable = 'off';
            app.CollectDataButton.Text = 'Collect Data';
            app.ContactLocalizationAppLabel = uilabel(app.UIFigure);
            app.ContactLocalizationAppLabel.Text = 'Contact Localization App';
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Enable = 'off';
            app.TrainModelButton.Text = 'Train Model';
            app.LocateSensorsButton = uibutton(app.UIFigure, 'push');
            app.LocateSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @LocateSensorsButtonPushed, true);
            app.LocateSensorsButton.Text = 'Locate Sensors';
            app.UseTrainedModelButton = uibutton(app.UIFigure, 'push');
            app.UseTrainedModelButton.ButtonPushedFcn = createCallbackFcn(app, @UseTrainedModelButtonPushed, true);
            app.UseTrainedModelButton.Enable = 'off';
            app.UseTrainedModelButton.Text = 'Use Trained Model';
            app.ArduinoConnectionStatusLampLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionStatusLampLabel.Text = 'Arduino Connection Status';
            app.ArduinoConnectionStatusLamp = uilamp(app.UIFigure);
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.ConnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduinoButtonPushed, true);
            app.ConnectArduinoButton.Enable = 'off';
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end