classdef Data_Collector_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        ResetCollectionButton        matlab.ui.control.Button
        ConfirmCollectionButton      matlab.ui.control.Button
        ClearPlotButton              matlab.ui.control.Button
        DataCollectorLabel           matlab.ui.control.Label
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        PLCountEditField             matlab.ui.control.NumericEditField
        PLCountEditFieldLabel        matlab.ui.control.Label
        RXCountEditField             matlab.ui.control.NumericEditField
        RXCountEditFieldLabel        matlab.ui.control.Label
        TXCountEditField             matlab.ui.control.NumericEditField
        TXCountEditFieldLabel        matlab.ui.control.Label
        StatusConsoleLabel           matlab.ui.control.Label
        CalibrationSamplesEditField  matlab.ui.control.NumericEditField
        CalibrationSamplesEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField         matlab.ui.control.EditField
        DescriptionEditFieldLabel    matlab.ui.control.Label
        SampleSizeEditField          matlab.ui.control.NumericEditField
        SampleSizeEditFieldLabel     matlab.ui.control.Label
        SaveLocationEditField        matlab.ui.control.EditField
        SaveLocationEditFieldLabel   matlab.ui.control.Label
        StartDataCollectionButton    matlab.ui.control.Button
        PointLogsGauge               matlab.ui.control.NinetyDegreeGauge
        PointLogsGaugeLabel          matlab.ui.control.Label
        ObjectFileDropDown           matlab.ui.control.DropDown
        ObjectFileDropDownLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        touchData % touchData Object (See VariableList.txt for more information)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, skinObj)
            global plottedName
            global plottedData
            plottedName = {};
            plottedData = {};
        end

        % Drop down opening function: ObjectFileDropDown
        function ObjectFileDropDownOpening(app, event)
            % Display all the contents in the "Obj_Files" directory
            listings = dir("Obj_Files");
            itemSet = {};
            itemSet{1} = '';
            for i = 3:size(listings, 1)
                itemSet{i-1} = listings(i).name;
            end
            app.ObjectFileDropDown.Items = itemSet;
            app.ObjectFileDropDown.Value = itemSet(1);
        end

        % Value changed function: ObjectFileDropDown
        function ObjectFileDropDownValueChanged(app, event)
            value = app.ObjectFileDropDown.Value;

            %Check if no selection was made
            if value == ""
                return
            end

            value = "Obj_Files/" + value;
            
            %Save the vertices and facets of the selected object and plot
            %them
            try
                [app.touchData.obj.f, app.touchData.obj.v] = read_obj(value);
            catch
                out = readObj(value);
                app.touchData.obj.f = out.f.v;
                app.touchData.obj.v = out.v;
            end
            
            %Plot the obj file 

            plotOBJ(app.UIAxes, app.touchData.obj.v, app.touchData.obj.f);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes, 'off');
            % Autoscale limits
            autoScale(app, app.UIAxes.Children(1));
            % Add to plotted data set
            global plottedName
            global plottedData
            plottedName{end+1} = app.ObjectFileDropDown.Value;
            plottedData{end+1} = app.UIAxes.Children(1);

        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            app.ObjectFileDropDown.Value = app.ObjectFileDropDown.Items(1);
            global plottedName
            global plottedData
            plottedName = {};
            plottedData = {};
        end

        % Button pushed function: StartDataCollectionButton
        function StartDataCollectionButtonPushed(app, event)
            global collect;
            collect = true;
            pause on;
            % Start by getting the stats of the plotted shape
            % Assume only one plot active
            if ~isempty(app.UIAxes.Children)
                global plottedName
                global plottedData
                [indx,~] = listdlg('PromptString',{'Select Object to Sample On'}, 'SelectionMode','single','ListString',plottedName,'ListSize',[200,100]);
                if ~isempty(indx)
                    data = plottedData{indx};
                    autoScale(app, data);
                    data.FaceColor = "green";
                    xBound = [min(data.XData,[],'All'), max(data.XData,[],'All')];
                    yBound = [min(data.YData,[],'All'), max(data.YData,[],'All')];
                    zBound = [min(data.ZData,[],'All'), max(data.ZData,[],'All')];
                    xWidth = xBound(2) - xBound(1);
                    yWidth = yBound(2) - yBound(1);
                    zWidth = zBound(2) - zBound(1);
                    avgWidth = mean([xWidth, yWidth, zWidth]);
                    numSamples = app.PLCountEditField.Value;
                    stretch = 5/(2*numSamples);
                    i = 1;
                    while collect == true && i < numSamples
                        % Generate a random vertice on the object,
                        % somewhere between all values of the vertices
                        currVert = getRandomSample(data);
                        %currVert = data.Vertices(randi([1, height(data.Vertices)]),:);

                        [x,y,z] = sphere;
                        x = x*avgWidth*stretch;
                        y = y*avgWidth*stretch;
                        z = z*avgWidth*stretch;
                        hold(app.UIAxes,'on')
                        surf(app.UIAxes, x+currVert(1), y+currVert(2), z+currVert(3),'FaceColor','red','EdgeColor','none');
                        hold(app.UIAxes,'off')
                        % Change view so that it clearly shows where the
                        % touch point is
                        view(app.UIAxes,currVert)
                        pause; % pauses until a button is pressed in the command window
                        if collect == true
                            app.PointLogsGauge.Value = i;
                            i = i+1;
                        end
                        delete(app.UIAxes.Children(1)); 

                        % Now stretch by 1/(2*numSamples) percent
                        % To get region to highlight
                        % region = [currVert(1) - stretch*xWidth, currVert(2) - stretch*yWidth, currVert(3) - stretch*zWidth; 
                        %           currVert(1) + stretch*xWidth, currVert(2) + stretch*yWidth, currVert(3) + stretch*zWidth];
                        % allVerts = data.Vertices(data.Vertices(:,1) > region(1,1) & data.Vertices(:,2) > region(1,2) & data.Vertices(:,3) > region(1,3) & data.Vertices(:,1) < region(2,1) & data.Vertices(:,2) < region(2,2) & data.Vertices(:,3) < region(2,3),:);
                        % try plotting on app.UIAxes these vertices
                        % hold(app.UIAxes,'on')
                        % scatter3(app.UIAxes, allVerts(:,1), allVerts(:,2), allVerts(:,3),5,'filled','red');
                        % hold(app.UIAxes,'off')
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Are you sure you want to close the app?', 'Confirm Close');
            if strcmpi(YN,'OK')
                global plottedName
                global plottedData
                plottedName = {};
                plottedData = {};
                delete(app)
            end
        end

        % Button pushed function: ConfirmCollectionButton
        function ConfirmCollectionButtonPushed(app, event)
            % Break loop
            global collect
            collect = false;

            % Collect/write data...
            disp("Wrote Collected Data")
        end

        % Button pushed function: ResetCollectionButton
        function ResetCollectionButtonPushed(app, event)
            % Break loop
            global collect
            collect = false;
            app.PointLogsGauge.Value = 0;
            
            % Reset data...
            disp("Reset Collection of Data")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ObjectFileDropDownLabel = uilabel(app.UIFigure);
            app.ObjectFileDropDownLabel.Text = 'Object File';
            app.ObjectFileDropDown = uidropdown(app.UIFigure);
            app.ObjectFileDropDown.Items = {'-'};
            app.ObjectFileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ObjectFileDropDownOpening, true);
            app.ObjectFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectFileDropDownValueChanged, true);
            app.ObjectFileDropDown.Value = '-';
            app.PointLogsGaugeLabel = uilabel(app.UIFigure);
            app.PointLogsGaugeLabel.Text = 'Point Logs';
            app.PointLogsGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.PointLogsGauge.Limits = [0 50];
            app.PointLogsGauge.MajorTicks = [0 25 50];
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'push');
            app.StartDataCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataCollectionButtonPushed, true);
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            app.SaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.SaveLocationEditField.Value = 'Data\';
            app.SampleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleSizeEditField.Value = 50;
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Value = 'Skin patch data';
            app.CalibrationSamplesEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationSamplesEditFieldLabel.Text = 'Calibration Samples';
            app.CalibrationSamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationSamplesEditField.Value = 50;
            app.StatusConsoleLabel = uilabel(app.UIFigure);
            app.StatusConsoleLabel.Text = 'StatusConsole';
            app.TXCountEditFieldLabel = uilabel(app.UIFigure);
            app.TXCountEditFieldLabel.Text = 'TX Count';
            app.TXCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.TXCountEditField.Value = 10;
            app.RXCountEditFieldLabel = uilabel(app.UIFigure);
            app.RXCountEditFieldLabel.Text = 'RX Count';
            app.RXCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXCountEditField.Value = 3;
            app.PLCountEditFieldLabel = uilabel(app.UIFigure);
            app.PLCountEditFieldLabel.Text = 'PL Count';
            app.PLCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLCountEditField.Value = 50;
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Random Sampling', 'Even Spacing'};
            app.MethodDropDown.Value = 'Random Sampling';
            app.DataCollectorLabel = uilabel(app.UIFigure);
            app.DataCollectorLabel.Text = 'Data Collector';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ConfirmCollectionButton = uibutton(app.UIFigure, 'push');
            app.ConfirmCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmCollectionButtonPushed, true);
            app.ConfirmCollectionButton.Text = 'Confirm Collection';
            app.ResetCollectionButton = uibutton(app.UIFigure, 'push');
            app.ResetCollectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCollectionButtonPushed, true);
            app.ResetCollectionButton.Text = 'Reset Collection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Collector_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end