classdef ContactLocalizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HomeDirectoryLabel           matlab.ui.control.Label
        SensorOutputLabel            matlab.ui.control.Label
        ReadSensorButton             matlab.ui.control.Button
        ConnectArduinoButton         matlab.ui.control.Button
        BaudRateEditField            matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel       matlab.ui.control.Label
        SerialPortEditField          matlab.ui.control.EditField
        SerialPortEditFieldLabel     matlab.ui.control.Label
        ArduinoConnectionStatusLamp  matlab.ui.control.Lamp
        ArduinoConnectionStatusLampLabel  matlab.ui.control.Label
        MachineLearningModelButton   matlab.ui.control.Button
        ValidateModelButton          matlab.ui.control.Button
        ContactLocalizationAppLabel  matlab.ui.control.Label
        CollectDataButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        skinObj % Arduino Connection
        homeDir; %Home Directory Path
        COM_num
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Immediately try to connect to arduino
            ConnectArduinoButtonPushed(app);

            app.homeDir = string(pwd);
            app.HomeDirectoryLabel.Text = app.homeDir;
            %Add all helper functions found in the scripts folder to path
            try
                addpath("Scripts");
                addpath("AppWindows");
            catch
                app.ArduinoConnectionStatusLampLabel.Text = "Scripts AddPath Failed!";
            end
            
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            Data_Collector_Window(app.skinObj, app.homeDir);
        end

        % Button pushed function: ValidateModelButton
        function ValidateModelButtonPushed(app, event)
            % Locate_Sensors_Window(app.homeDir);
            Validate_Model_Window(app.skinObj, app.homeDir);
        end

        % Button pushed function: MachineLearningModelButton
        function MachineLearningModelButtonPushed(app, event)
            Test_Model_Window(app.skinObj, app.homeDir);
        end

        % Button pushed function: ConnectArduinoButton
        function ConnectArduinoButtonPushed(app, event)
            %Attempt to connect to arduino 

            app.ArduinoConnectionStatusLampLabel.Text = "Connecting to Aruduino...";

            connectionsList = serialportlist("available");

            if isempty(connectionsList)
                %Connection Failed!
                app.ArduinoConnectionStatusLampLabel.Text = "Connection Failed!";
                app.ArduinoConnectionStatusLamp.Color = [1,0,0]; %Set color to red
                app.ConnectArduinoButton.Enable = "on";
                return;
            end


            for i = 1:length(connectionsList)
                try
                    %Make Connection
                    app.SerialPortEditField.Value = connectionsList(i);
                    app.skinObj = serialport(app.SerialPortEditField.Value,app.BaudRateEditField.Value);
                    ReadSensorButtonPushed(app);
    
                    % Connection Succesful!
                    app.ArduinoConnectionStatusLampLabel.Text = "Connection Successful!";
                    app.ArduinoConnectionStatusLamp.Color = [0,1,0]; %Set color to green
                    app.CollectDataButton.Enable = "on";
                    app.MachineLearningModelButton.Enable = "on";
                    app.ConnectArduinoButton.Enable = "off";

                    return;
                catch
                    %Connection Failed!
                    app.ArduinoConnectionStatusLampLabel.Text = "Connection Failed!";
                    app.ArduinoConnectionStatusLamp.Color = [1,0,0]; %Set color to red
                    app.ConnectArduinoButton.Enable = "on";
                end

            end

        end

        % Button pushed function: ReadSensorButton
        function ReadSensorButtonPushed(app, event)
            dataClean = readSkin(app.skinObj);
            dataCleanString = "Data Size: " + num2str(length(dataClean));
            %dataCleanString = strrep(dataCleanString, ' ', ', ');
            app.SensorOutputLabel.Text = dataCleanString;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CollectDataButton = uibutton(app.UIFigure, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Enable = 'off';
            app.CollectDataButton.Text = 'Collect Data';
            app.ContactLocalizationAppLabel = uilabel(app.UIFigure);
            app.ContactLocalizationAppLabel.Text = 'Contact Localization App';
            app.ValidateModelButton = uibutton(app.UIFigure, 'push');
            app.ValidateModelButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateModelButtonPushed, true);
            app.ValidateModelButton.Text = 'Validate Model';
            app.MachineLearningModelButton = uibutton(app.UIFigure, 'push');
            app.MachineLearningModelButton.ButtonPushedFcn = createCallbackFcn(app, @MachineLearningModelButtonPushed, true);
            app.MachineLearningModelButton.Enable = 'off';
            app.MachineLearningModelButton.Text = 'Machine Learning Model';
            app.ArduinoConnectionStatusLampLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionStatusLampLabel.Text = 'Arduino Connection Status';
            app.ArduinoConnectionStatusLamp = uilamp(app.UIFigure);
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.Value = 'COM4';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.ValueDisplayFormat = '%.0f';
            app.BaudRateEditField.Value = 115200;
            app.ConnectArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnectArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduinoButtonPushed, true);
            app.ConnectArduinoButton.Text = 'Connect Arduino';
            app.ReadSensorButton = uibutton(app.UIFigure, 'push');
            app.ReadSensorButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSensorButtonPushed, true);
            app.ReadSensorButton.Text = 'Read Sensor';
            app.SensorOutputLabel = uilabel(app.UIFigure);
            app.SensorOutputLabel.Text = 'SensorOutput';
            app.HomeDirectoryLabel = uilabel(app.UIFigure);
            app.HomeDirectoryLabel.Text = 'HomeDirectory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContactLocalizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end